classdef SwingarmOptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SwingarmOptimizationAppLabel    matlab.ui.control.Label
        ClearconsoleoutputButton        matlab.ui.control.Button
        OptimizationwithPatternSearchPanel  matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        OptimizeSwingarmButton          matlab.ui.control.Button
        MaxfunctionevalsEditField       matlab.ui.control.NumericEditField
        MaxfunctiosevalsLabel           matlab.ui.control.Label
        MaxiterationsEditField          matlab.ui.control.NumericEditField
        MaxiterationsEditFieldLabel     matlab.ui.control.Label
        InitialguesscmEditField         matlab.ui.control.NumericEditField
        InitialguesscmEditFieldLabel    matlab.ui.control.Label
        ManualdesignupdatePanel         matlab.ui.container.Panel
        RunAnalysisforDesignUpdateButton  matlab.ui.control.Button
        DesignupdatecmEditField_2       matlab.ui.control.NumericEditField
        DesignupdatecmLabel             matlab.ui.control.Label
        SelectDesignVariableDropDown    matlab.ui.control.DropDown
        SelectDesignVariableDropDownLabel  matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ResultsTab                      matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        DesignupdatecmEditField         matlab.ui.control.NumericEditField
        DesignupdatecmEditFieldLabel    matlab.ui.control.Label
        AnglebetweenadjacentfacesEditField  matlab.ui.control.NumericEditField
        AnglebetweenadjacentfacesLabel  matlab.ui.control.Label
        StrainenergyNmEditField         matlab.ui.control.NumericEditField
        StrainenergyNmEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PostprocTab                     matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        DesignVariablesTab              matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Var3Label                       matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Var2Label                       matlab.ui.control.Label
        Var1Label                       matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        SwingarmSetupTab                matlab.ui.container.Tab
        Image9                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        geometryFunctions = py.None % Auxiliary functions created using PythonOCC
        myGeomObj = py.None; % App created using PythonOCC
        shapeSolid = py.None; % Solid shape that can be exported to a geometry file (STEP, or IGES)
        IGESFileName = fullfile(".", "geometry", "swingarm brep.igs");
        STEPFileName = fullfile('.', 'metadata', 'outputFile.step'); % Name of the STEP-file written out using PythonOCC
        stopOptimization = false; % Description
        result = pde.StaticStructuralResults; % pde.StaticStructuralResults
    end
    
    methods (Access = private)
        
        
        function updateLogInformation(app, logInfo)
            % Get the current text in the log area
            currentLogInformation = app.LogTextArea.Value;

            % Append the new message
            newLogInformation = [currentLogInformation; logInfo];

            % Update the log area with the new text
            app.LogTextArea.Value = newLogInformation;
            scroll(app.LogTextArea, 'bottom')
        end
        
        function visualizeSolidGeometry(app)
            % Create a structural problem and assign it the updated
            % geometry
            structModel = femodel(AnalysisType="structuralStatic", Geometry=app.STEPFileName);

            % Plot the PDE geometry and the postprocessing results
            if exist(fullfile('.', 'metadata', 'outputFile.step'), "file")
                % PDE geometry
                pdegplot(structModel, VertexLabels="off", EdgeLabels="off", ...
                    FaceLabels="off", CellLabels="off", FaceAlpha=0.5, Parent=app.UIAxes);
            else
                updateLogInformation(app, sprintf("File %s does not exist", fullfile('.', 'metadata')))
            end
        end
        
        function verifyMetadataFolder(~)
            if ~exist(fullfile('.', 'metadata'), "dir")
                mkdir(fullfile('.', 'metadata'))
            end
        end

        function isFile = verifyMatadataFile(~)
            isFile = true;
            if ~exist(fullfile('.', 'metadata', 'outputFile.step'), "file")
                isFile = false;
            end
        end

        function computeDesignVariablesData(app, value)
            try
                switch value
                    case "Var 1"
                        edgeId = 34;
                    case "Var 2"
                        edgeId = 11;
                    case "Var 3"
                        edgeId = 3;
                    otherwise
                        error(sprtinf("Only 'Var 1', 'Var 2', and 'Var 3' can be selected, the current selection is %s", value))
                end
                updateLogInformation(app, sprintf("Selected Design Variable %s", value))
                updateLogInformation(app, newline)
            catch ME
                updateLogInformation(app, sprintf("File %s does not exist", fullfile('.', 'metadata')))
            end
            app.myGeomObj.selectedEdges = py.list([]);
            selectedEdge = getSelectedEdge(app.myGeomObj.baseShape, edgeId);
            app.myGeomObj.selectedEdges.append(selectedEdge)
            app.myGeomObj.getDataDesignVariables(py.int(0))
            app.myGeomObj.computeNumDesignVariables()
        end

        function strainEnergy = computeStrainEnergy(app, isOpt)
            try
                % Import geometry using STEP-file
                isFile = app.verifyMatadataFile();
                if ~isFile
                    updateLogInformation(app, strcat("Error: ", sprintf("File %s cannot be found", app.STEPFileName)))
                end
                
                % Initialize the structural problem
                structModel = femodel(AnalysisType="structuralStatic", Geometry=app.STEPFileName);
    
                % Apply material properties
                structModel.MaterialProperties = materialProperties(YoungsModulus=200e9, ...
                                                                    PoissonsRatio=0.28, ...
                                                                    MassDensity=7800);
    
                % Apply boundary conditions
                structModel = applyBoundaryConditions(structModel);
    
                % Generate mesh
                structModel = generateMesh(structModel, "Hmax", 0.02, "Hmin", 0.005);
    
                % Solve the structural problem using the Finite Element Method
                app.result = solve(structModel);
    
                % Compute the strain energy based on the solution
                femMatrices = assembleFEMatrices(structModel);
                dispVct = [app.result.Displacement.ux; app.result.Displacement.uy; app.result.Displacement.uz];
                strainEnergy = transpose(dispVct)*femMatrices.K*dispVct;
            catch ME
                if strcmp(ME.identifier, 'pde:pdeMeshGenerator:MesherFailedInternal') && ~isOpt
                    msg = sprintf("Your design update %d created a geometry that cannot be meshed. Please choose another value and inspect the resulting geometry", app.DesignupdatecmEditField_2.Value);
                else
                    msg = strcat("Error: ", ME.message, newline);
                end
                app.updateLogInformation(msg)
                strainEnergy = inf;
            end
        end
        
        function thetaUpdated = computeAngleAdjacentFaces(app)
            if isequal(app.myGeomObj.updatedShape, py.None)
                myShape = app.myGeomObj.baseShape;
            else
                myShape = app.myGeomObj.updatedShape;
            end
            pyArrUpdated = app.myGeomObj.getNormalsOnFacesAdjacentToSelectedEdges(myShape);
            thetaUpdated = computeAngleBetweenAdjacentFaces(pyArrUpdated);
        end
        
        
        function strainEnergy = computeStrainEnergyCostFun(app, d)
            % Increment the chosen design variable as instructed by the
            % optimizer
            app.myGeomObj.updateShape(py.list(num2cell(d)));

            % Create a solid from the B-Rep
            pyTuple = app.geometryFunctions.createSolidFromBRepCompound(app.myGeomObj.updatedShape);

            % Export the geometry to a file
            app.geometryFunctions.writeGeometryToFile(app.STEPFileName, pyTuple{1})

            % Compute the strain energy for the structural analysis of the
            % swingarm based on the updated shape
            strainEnergy = app.computeStrainEnergy(true);
        end
        
        function [theta, ceq] = computeAngleConstraint(app, d)
            % Increment the chosen design variable as instructed by the
            % optimizer
            app.myGeomObj.updateShape(py.list(num2cell(d)));

            % Compute the angle between the adjacent faces that contain the
            % edge
            pyArr = app.myGeomObj.getNormalsOnFacesAdjacentToSelectedEdges(app.myGeomObj.updatedShape);
            theta = - computeAngleBetweenAdjacentFaces(pyArr);

            % Update the logging values on the App
            app.AnglebetweenadjacentfacesEditField.Value = theta;

            % There are no equality constraints
            ceq = [];
        end
        

        function [stop, opts, optschanged] = myOutputFunGOT(app, optimValues, opts, ~)
            % Update shape based on the design update
            try
                app.myGeomObj.updateShape(py.list(num2cell(optimValues.x)));
                app.createSolidShapeFromBRepCompound(app.myGeomObj.updatedShape)
                app.visualizeSolidGeometry()
            catch ME
                updateLogInformation(app, strcat("Error: ", ME.message))
            end

            % Format the current output
            currentOutput = sprintf('No.: %d, Design: %d Objective: %f\n', ...
                optimValues.iteration, optimValues.x, optimValues.fval);

            % Print the output in the log console
            app.updateLogInformation(currentOutput)

            % Update the logging values on the App
            app.DesignupdatecmEditField.Value = optimValues.x;
            app.StrainenergyNmEditField.Value = optimValues.fval;

            % Refresh the GUI
            drawnow;

            % Set the stop flag
            stop = app.stopOptimization;
            if stop
                updateLogInformation(app, strcat("Optimization stopped by the user", newline))
            end

            % Set the flag on whether the optimization options have changed
            optschanged = false;
        end
        
        function createSolidShapeFromBRep(app, fileName)
            app.myGeomObj.readGeometryFile(fileName)
            pyTuple = app.geometryFunctions.createSolidFromBRepCompound(app.myGeomObj.baseShape);
            app.shapeSolid = pyTuple{1};
            app.verifyMetadataFolder();
            app.geometryFunctions.writeGeometryToFile(app.STEPFileName, app.shapeSolid)
        end
        
        function createSolidShapeFromBRepCompound(app, shape)
            pyTuple = app.geometryFunctions.createSolidFromBRepCompound(shape);
            app.geometryFunctions.writeGeometryToFile(app.STEPFileName, pyTuple{1})
        end
        
        function visualizePostproc(app)
            % Plot the postprocessing results of the PDE solution
            if exist(fullfile('.', 'metadata', 'outputFile.step'), "file")
                % Displacement (norm)
                if ~isempty(app.result.Displacement)
                    disp = app.result.Displacement.Magnitude;
                    if ~isempty(disp)
                        pdeplot3D(app.result.Mesh, 'ColorMapData', abs(real(disp)), 'Deformation', ...
                            app.result.Displacement, 'Parent', app.UIAxes2);
                    end
                end
                title("Displacement (norm)", 'Parent', app.UIAxes2)
                
                if ~isempty(app.result.VonMisesStress)
                    % von Mises stresses
                    vonMisesStress = app.result.VonMisesStress;
                    if ~isempty(vonMisesStress)
                        pdeplot3D(app.result.Mesh, 'ColorMapData', vonMisesStress, 'Parent', app.UIAxes2_2);
                    end
                end
                title("von Mises stresses", 'Parent', app.UIAxes2_2)
            else
                updateLogInformation(app, sprintf("File %s does not exist", fullfile('.', 'metadata')))
            end
        end
        
        function resetPostproc(app)
            cla(app.UIAxes2, 'reset');
            view(app.UIAxes2, 2);
            title("Displacement (norm)", 'Parent', app.UIAxes2)
            cla(app.UIAxes2_2, 'reset');
            view(app.UIAxes2_2, 2);
            title("von Mises stresses", 'Parent', app.UIAxes2_2)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import the custom Python module leveraging PythonOCC
            % functionality
            app.geometryFunctions = env_conf(true, true);

            % Create an object containing the Geometry
            app.myGeomObj = app.geometryFunctions.MyApp();

            % Create solid shape from B-Rep and visualize it
            try
                app.createSolidShapeFromBRep(app.IGESFileName)
                app.visualizeSolidGeometry()
            catch ME
                updateLogInformation(app, strcat("Error: ", ME.message))
            end
            
            % Compute design variable data based on the default selection
            computeDesignVariablesData(app, app.SelectDesignVariableDropDown.Value)
        end

        % Button pushed function: RunAnalysisforDesignUpdateButton
        function RunAnalysisforDesignUpdateButtonPushed(app, event)
            % Solve the Finite Element equation system
            updateLogInformation(app, strcat("Computing strain energy and angle of adjacent faces ...", newline))

            % Compute the strain energy
            strainE = app.computeStrainEnergy(false);

            % Display the value of the strain energy
            app.StrainenergyNmEditField.Value = strainE;

            % Compute angle between the adjacent faces of the selected edge
            theta = app.computeAngleAdjacentFaces();

            % Display the value of the angle between the adjacent faces
            app.AnglebetweenadjacentfacesEditField.Value = theta;

            % Visualize the postprocessing results
            app.visualizePostproc();
        end

        % Value changed function: SelectDesignVariableDropDown
        function SelectDesignVariableDropDownValueChanged(app, event)
            % Get the current value of the dropdown list
            value = app.SelectDesignVariableDropDown.Value;

            % Compute the new design variable data
            app.computeDesignVariablesData(value);

            % Get the selected value of the design update
            d = app.DesignupdatecmEditField_2.Value;

            % Update the base shape based on the design update
            app.myGeomObj.updateShape(py.list(num2cell(d)));

             % Create a solid from the B-Rep
            pyTuple = app.geometryFunctions.createSolidFromBRepCompound(app.myGeomObj.updatedShape);

            % Export the geometry to a file
            app.geometryFunctions.writeGeometryToFile(app.STEPFileName, pyTuple{1})

            % Visualize the new geometry
            app.visualizeSolidGeometry();

            % Clear the figures in the postprocessing tab
            app.resetPostproc();
        end

        % Value changed function: DesignupdatecmEditField_2
        function DesignupdatecmEditField_2ValueChanged(app, event)
            % Get the displacement value defined by the user
            d = app.DesignupdatecmEditField_2.Value;

            % Register the value of the design update
            app.DesignupdatecmEditField.Value = d;

            % Update and visualize the base shape
            try
                app.myGeomObj.updateShape(py.list(num2cell(d)));
                app.createSolidShapeFromBRepCompound(app.myGeomObj.updatedShape)
                app.visualizeSolidGeometry()
            catch ME
                updateLogInformation(app, strcat("Error: ", ME.message))
            end
        end

        % Button pushed function: OptimizeSwingarmButton
        function OptimizeSwingarmButtonPushed(app, event)
            % Set the stop optimization flag
            app.stopOptimization = false;
            
            % Get the optimization algorithm
            myAlgorithm = @patternsearch;
            updateLogInformation(app, strcat("Optimization started by the user"))
            updateLogInformation(app, newline)

            % Define optimization options
            outputFcn = @app.myOutputFunGOT;
            opts = optimoptions(myAlgorithm, MaxIterations=app.MaxiterationsEditField.Value, ...
                MaxFunctionEvaluations=app.MaxfunctionevalsEditField.Value, Display="none", ...
                OutputFcn=outputFcn);

            % Perform sizing optimization and report back the results
            [dOpt, strainEOpt] = myAlgorithm(@app.computeStrainEnergyCostFun, ...
            app.InitialguesscmEditField.Value, [], [], [], [], zeros(size(app.InitialguesscmEditField.Value)), ...
            [], @(d)app.computeAngleConstraint(d), opts);
            updateLogInformation(app, newline)
            updateLogInformation(app, "Optimization finished! Increase the maximum number of iterations and the maximum number of function evaluations if you are not satisfied")
            updateLogInformation(app, newline)

            % Register the optimal value of the design update
            app.DesignupdatecmEditField.Value = dOpt;

            % Register the value of the minimum strain energy found
            app.StrainenergyNmEditField.Value = strainEOpt;

            % Compute the angle between the adjacent faces of the
            % selected edge
            [thetaOpt, ~] = app.computeAngleConstraint(dOpt);

            % Register the angle between the adjacent faces of the
            % selected edge
            app.AnglebetweenadjacentfacesEditField.Value = thetaOpt;            
        end

        % Button pushed function: ClearconsoleoutputButton
        function ClearconsoleoutputButtonPushed(app, event)
            app.LogTextArea.Value = '';
            updateLogInformation(app, sprintf("Selected Design Variable %s", app.SelectDesignVariableDropDown.Value))
            updateLogInformation(app, newline)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopOptimization = true;
        end

        % Callback function
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            try
                switch value
                    case "fmincon"
                        app.MaxiterationsEditField.Value = 20;
                        app.MaxiterationsEditField.UpperLimitInclusive = 100;
                    case "Pattern search"
                        app.MaxiterationsEditField.Value = 2;
                        app.MaxiterationsEditField.UpperLimitInclusive = 4;
                    otherwise
                        error("The chose optimization algorithm can only be either 'fmincon', or Pattern search")
                end
            catch ME
                updateLogInformation(app, strcat("Error: ", ME.message))
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LogTextArea = uitextarea(app.LeftPanel);
            app.SelectDesignVariableDropDownLabel = uilabel(app.LeftPanel);
            app.SelectDesignVariableDropDownLabel.Text = 'Select Design Variable';
            app.SelectDesignVariableDropDown = uidropdown(app.LeftPanel);
            app.SelectDesignVariableDropDown.Items = {'Var 1', 'Var 2', 'Var 3'};
            app.SelectDesignVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDesignVariableDropDownValueChanged, true);
            app.SelectDesignVariableDropDown.Value = 'Var 1';
            app.ManualdesignupdatePanel = uipanel(app.LeftPanel);
            app.ManualdesignupdatePanel.Title = 'Manual design update';
            app.DesignupdatecmLabel = uilabel(app.ManualdesignupdatePanel);
            app.DesignupdatecmLabel.Text = 'Design update [cm]';
            app.DesignupdatecmEditField_2 = uieditfield(app.ManualdesignupdatePanel, 'numeric');
            app.DesignupdatecmEditField_2.Limits = [-100 100];
            app.DesignupdatecmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DesignupdatecmEditField_2ValueChanged, true);
            app.RunAnalysisforDesignUpdateButton = uibutton(app.ManualdesignupdatePanel, 'push');
            app.RunAnalysisforDesignUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisforDesignUpdateButtonPushed, true);
            app.RunAnalysisforDesignUpdateButton.Text = 'Run Analysis for Design Update';
            app.OptimizationwithPatternSearchPanel = uipanel(app.LeftPanel);
            app.OptimizationwithPatternSearchPanel.Title = 'Optimization with Pattern Search';
            app.InitialguesscmEditFieldLabel = uilabel(app.OptimizationwithPatternSearchPanel);
            app.InitialguesscmEditFieldLabel.Text = 'Initial guess [cm]';
            app.InitialguesscmEditField = uieditfield(app.OptimizationwithPatternSearchPanel, 'numeric');
            app.InitialguesscmEditField.Limits = [-100 100];
            app.MaxiterationsEditFieldLabel = uilabel(app.OptimizationwithPatternSearchPanel);
            app.MaxiterationsEditFieldLabel.Text = 'Max. iterations';
            app.MaxiterationsEditField = uieditfield(app.OptimizationwithPatternSearchPanel, 'numeric');
            app.MaxiterationsEditField.Limits = [0 100];
            app.MaxiterationsEditField.Value = 2;
            app.MaxfunctiosevalsLabel = uilabel(app.OptimizationwithPatternSearchPanel);
            app.MaxfunctiosevalsLabel.Text = 'Max. function evals.';
            app.MaxfunctionevalsEditField = uieditfield(app.OptimizationwithPatternSearchPanel, 'numeric');
            app.MaxfunctionevalsEditField.Limits = [0 10000];
            app.MaxfunctionevalsEditField.Value = 4;
            app.OptimizeSwingarmButton = uibutton(app.OptimizationwithPatternSearchPanel, 'push');
            app.OptimizeSwingarmButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeSwingarmButtonPushed, true);
            app.OptimizeSwingarmButton.Text = 'Optimize Swingarm';
            app.StopButton = uibutton(app.OptimizationwithPatternSearchPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearconsoleoutputButton = uibutton(app.LeftPanel, 'push');
            app.ClearconsoleoutputButton.ButtonPushedFcn = createCallbackFcn(app, @ClearconsoleoutputButtonPushed, true);
            app.ClearconsoleoutputButton.Text = 'Clear console output';
            app.SwingarmOptimizationAppLabel = uilabel(app.LeftPanel);
            app.SwingarmOptimizationAppLabel.Text = 'Swingarm Optimization App';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            title(app.UIAxes, 'Optimized Shape')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ResultsPanel = uipanel(app.ResultsTab);
            app.ResultsPanel.Title = 'Results';
            app.StrainenergyNmEditFieldLabel = uilabel(app.ResultsPanel);
            app.StrainenergyNmEditFieldLabel.Text = 'Strain energy [Nm]';
            app.StrainenergyNmEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.AnglebetweenadjacentfacesLabel = uilabel(app.ResultsPanel);
            app.AnglebetweenadjacentfacesLabel.Text = 'Angle between adjacent faces [°]';
            app.AnglebetweenadjacentfacesEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.DesignupdatecmEditFieldLabel = uilabel(app.ResultsPanel);
            app.DesignupdatecmEditFieldLabel.Text = 'Design update [cm]';
            app.DesignupdatecmEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PostprocTab = uitab(app.TabGroup);
            app.PostprocTab.Title = 'Postproc';
            app.UIAxes2 = uiaxes(app.PostprocTab);
            title(app.UIAxes2, 'Displacement (norm)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.PostprocTab);
            title(app.UIAxes2_2, 'von Mises stresses')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.DesignVariablesTab = uitab(app.TabGroup);
            app.DesignVariablesTab.Title = 'Design Variables';
            app.Image = uiimage(app.DesignVariablesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar1.png');
            app.Image2 = uiimage(app.DesignVariablesTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar1 optimized.png');
            app.Var1Label = uilabel(app.DesignVariablesTab);
            app.Var1Label.Text = 'Var 1';
            app.Var2Label = uilabel(app.DesignVariablesTab);
            app.Var2Label.Text = 'Var 2';
            app.Image3 = uiimage(app.DesignVariablesTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar2.png');
            app.Image4 = uiimage(app.DesignVariablesTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar2 optimized.png');
            app.Var3Label = uilabel(app.DesignVariablesTab);
            app.Var3Label.Text = 'Var 3';
            app.Image5 = uiimage(app.DesignVariablesTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar3.png');
            app.Image6 = uiimage(app.DesignVariablesTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'images', 'designVar3 optimized.png');
            app.SwingarmSetupTab = uitab(app.TabGroup);
            app.SwingarmSetupTab.Title = 'Swingarm Setup';
            app.Image8 = uiimage(app.SwingarmSetupTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'images', 'swingarm.png');
            app.Image9 = uiimage(app.SwingarmSetupTab);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'images', 'swingarm - technical characteristics (2).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwingarmOptimizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end